% description: Get unread mails from inbox of Microsoft(R) Outlook(R) via ActiveXServer and show them on tiles as word cloud images.
% Copyright 2019 The MathWorks, Inc.
classdef urwc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnReadWordCloudUIFigure  matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        RefreshButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function displayWordCloudTiles(app)
            wcPlotSize = [5 4]; % wcの表示数

            wcCount = wcPlotSize(1)*wcPlotSize(2);
            tileSize = [1 1] ./ wcPlotSize;
            positions = {};
            for yIndex =  wcPlotSize(2):-1:1
                for xIndex = 1:wcPlotSize(1)
                    positions{end+1} = [tileSize(1) * (xIndex - 1), ...
                        tileSize(2) * (yIndex - 1), ...
                        tileSize(1), ...
                        tileSize(2)]; %#ok<AGROW>
                end
            end
            
            oApp = actxserver('Outlook.Application');
            oNS = oApp.GetNamespace('mapi');
            oFolder = oNS.GetDefaultFolder(6);
            oDeletedFolder = oNS.GetDefaultFolder(3);            
            items = oFolder.Items;
            
            count = items.Count;
            mails = {};
            for index = count:-1:1
                mail = items.Item(index);
                if mail.get('UnRead')
                    mails{end+1} = mail; %#ok<AGROW>
                end
            end
            
            if wcCount > numel(mails)
                wcCount = numel(mails);
            end
            f = app.Panel;
            addprop(f,'mails');
            f.mails = mails;
            
            pixcelPosiitonX = f.InnerPosition(3);
            pixcelPosiitonY = f.InnerPosition(4);
            
            for index = 1:wcCount
                txt = mails{index}.get('Body');
                %% Panels and word cloud
                pixcelPosition = positions{index} .* [pixcelPosiitonX pixcelPosiitonY pixcelPosiitonX pixcelPosiitonY];
                p(index) = uipanel(f,'Position',pixcelPosition,'AutoResizeChildren','on'); %#ok<AGROW>
                wc(index) = wordcloud(p(index), txt,'MaxDisplayWords',32,'Position',[0 0.2 1 0.8]); %#ok<AGROW,NASGU>
               %% Buttons
                panelX = p(index).InnerPosition(3);
                panelY = p(index).InnerPosition(4);
                pixcelPosition = [0 0 0.5 0.2] .* [panelX panelY panelX panelY];
                uibutton(p(index),'Text', 'Open',...
                    'ButtonPushedFcn', @(src,eventdata) f.mails{index}.Display(),...
                    'Position',pixcelPosition);   
                pixcelPosition = [0.5 0 0.5 0.2] .* [panelX panelY panelX panelY];
                uibutton(p(index),'Text', 'Trash',...
                    'ButtonPushedFcn', @(src,eventdata)f.mails{index}.Move(oDeletedFolder),...
                    'Position',pixcelPosition);   
            end
        end
        
        function removeWordCloudTiles(app)
            % remove current word cloud tiles
            f = app.Panel;
            panels = f.Children;
            if numel(panels) > 0
                pCount = numel(panels);
                for index = pCount:-1:1
                    panels(index).delete
                end
            end
            rmprops(f,'mails')
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            displayWordCloudTiles(app);    
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % remove current word cloud tiles
            removeWordCloudTiles(app)
            
            % show word cloud tiles
            displayWordCloudTiles(app); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnReadWordCloudUIFigure = uifigure('Visible', 'off');
            app.UnReadWordCloudUIFigure.Name = 'UnRead WordCloud';
            app.Panel = uipanel(app.UnReadWordCloudUIFigure);
            app.RefreshButton = uibutton(app.UnReadWordCloudUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UnReadWordCloudUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = urwc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnReadWordCloudUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnReadWordCloudUIFigure)
        end
    end
end